classdef NAVecoV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DonnesdentreTab                 matlab.ui.container.Tab
        SuivantButton                   matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        InformationsduvhiculePanel      matlab.ui.container.Panel
        NouveauVhiculeButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Vhicule1DropDown_2Label         matlab.ui.control.Label
        Vhicule1DropDown_2              matlab.ui.control.DropDown
        Vhicule2Label                   matlab.ui.control.Label
        Vhicule2DropDown                matlab.ui.control.DropDown
        InformationdupoidsPanel         matlab.ui.container.Panel
        PassagersApprox75KgSliderLabel  matlab.ui.control.Label
        PassagersApprox75KgSlider       matlab.ui.control.Slider
        ChargeKgSliderLabel             matlab.ui.control.Label
        ChargeKgSlider                  matlab.ui.control.Slider
        InformationsdutrajetPanel       matlab.ui.container.Panel
        AdressededpartEditFieldLabel    matlab.ui.control.Label
        AdressededpartEditField         matlab.ui.control.EditField
        AdressedarriveEditFieldLabel    matlab.ui.control.Label
        AdressedarriveEditField         matlab.ui.control.EditField
        hhDropDownLabel                 matlab.ui.control.Label
        hhDropDown                      matlab.ui.control.DropDown
        HeuredarriveLabel               matlab.ui.control.Label
        mmDropDownLabel                 matlab.ui.control.Label
        mmDropDown                      matlab.ui.control.DropDown
        TolrancedutempsLabel            matlab.ui.control.Label
        hhDropDown_2Label               matlab.ui.control.Label
        hhDropDown_2                    matlab.ui.control.DropDown
        mmDropDown_2Label               matlab.ui.control.Label
        mmDropDown_2                    matlab.ui.control.DropDown
        ParamtresTab                    matlab.ui.container.Tab
        SegmentationPanel               matlab.ui.container.Panel
        InterpolationdelapenteCheckBox  matlab.ui.control.CheckBox
        EchantillonnagededonnemSpinnerLabel  matlab.ui.control.Label
        EchantillonnagededonnemSpinner  matlab.ui.control.Spinner
        ParamtresdelasegmentationPanel  matlab.ui.container.Panel
        VitesseMaxCheckBox              matlab.ui.control.CheckBox
        PenteCheckBox                   matlab.ui.control.CheckBox
        IntersectionCheckBox            matlab.ui.control.CheckBox
        OptionpluieCheckBox             matlab.ui.control.CheckBox
        TempsdsirparleconducteursEditFieldLabel  matlab.ui.control.Label
        TempsdsirparleconducteursEditField  matlab.ui.control.NumericEditField
        RetournerButton_3               matlab.ui.control.Button
        SuivantButton_3                 matlab.ui.control.Button
        Image2_4                        matlab.ui.control.Image
        ParamtredecontroleoptimalButtonGroup  matlab.ui.container.ButtonGroup
        FacteurdevitesseSliderLabel     matlab.ui.control.Label
        FacteurdevitesseSlider          matlab.ui.control.Slider
        FacteurdedistanceSliderLabel    matlab.ui.control.Label
        FacteurdedistanceSlider         matlab.ui.control.Slider
        FacteurdnergieSliderLabel       matlab.ui.control.Label
        FacteurdnergieSlider            matlab.ui.control.Slider
        FacteurdeconfortSliderLabel     matlab.ui.control.Label
        FacteurdeconfortSlider          matlab.ui.control.Slider
        ParamtredoptimisationPanel      matlab.ui.container.Panel
        MinnergieCheckBox               matlab.ui.control.CheckBox
        MaxdistanceCheckBox             matlab.ui.control.CheckBox
        MaxvitesseCheckBox              matlab.ui.control.CheckBox
        MintempsCheckBox_3              matlab.ui.control.CheckBox
        EQdistanceCheckBox              matlab.ui.control.CheckBox
        EQvitesseCheckBox               matlab.ui.control.CheckBox
        BoucleOuverteCheckBox           matlab.ui.control.CheckBox
        BoucleFermeCheckBox             matlab.ui.control.CheckBox
        ConfortCheckBox                 matlab.ui.control.CheckBox
        NaviguerTab                     matlab.ui.container.Tab
        RetournerButton                 matlab.ui.control.Button
        GOButton                        matlab.ui.control.Button
        ProgressionPanel                matlab.ui.container.Panel
        SegmentationLabel               matlab.ui.control.Label
        CalculditinraireLabel           matlab.ui.control.Label
        DtectiondescontraintesLabel     matlab.ui.control.Label
        DtectiondetraficLabel_2         matlab.ui.control.Label
        SegmentationdetrajetLabel       matlab.ui.control.Label
        OptimisationditinraireBOLabel   matlab.ui.control.Label
        CalculditinraireoptimalLabel    matlab.ui.control.Label
        CalculdnergieminimaleLabel      matlab.ui.control.Label
        OptimisationactivednergieBOBFLabel  matlab.ui.control.Label
        ConfigurationdeloptimisationLabel  matlab.ui.control.Label
        StatutdetrajetLabel             matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        CaractristiquesdelitinraireEstimparGooglePanel  matlab.ui.container.Panel
        HeuredarriveestimehhmmssLabel   matlab.ui.control.Label
        DurehhmmssLabel                 matlab.ui.control.Label
        VariationdepenteestimeLabel     matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        DistanceestimeKmLabel           matlab.ui.control.Label
        OutToleranceLabel               matlab.ui.control.Label
        EnregistreurPanel               matlab.ui.container.Panel
        LoggerTextArea                  matlab.ui.control.TextArea
        Image5                          matlab.ui.control.Image
        AnalyserTab_2                   matlab.ui.container.Tab
        RetournerButton_2               matlab.ui.control.Button
        ExporterButton                  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        RfrencededistanceCheckBox       matlab.ui.control.CheckBox
        LimitationdevitesseCheckBox     matlab.ui.control.CheckBox
        LimitationdacclrationCheckBox   matlab.ui.control.CheckBox
        DistanceCheckBox                matlab.ui.control.CheckBox
        VitesseCheckBox                 matlab.ui.control.CheckBox
        AcclrationCheckBox              matlab.ui.control.CheckBox
        Energievhicule1CheckBox         matlab.ui.control.CheckBox
        Energievhicule2CheckBox         matlab.ui.control.CheckBox
        WLTPCheckBox                    matlab.ui.control.CheckBox
        VitesseMaximaleCheckBox         matlab.ui.control.CheckBox
        NiveaudoptimisationLabel        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        DiffrencednergieavecrfrenceLabel  matlab.ui.control.Label
        Image2_3                        matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        PlotButton                      matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');
            
            Length = size(MyFolderInfo,1);
            Data   = []; 
            
            for i=1:Length
                if length(MyFolderInfo(i).name)>=4
                    if (MyFolderInfo(i).name(end-3:end)=='.mat')
                        MyFolderInfo(i).name
                        Data = [Data;string(MyFolderInfo(i).name)];
                    end
                end
            end
            app.Vhicule1DropDown_2.Items = cellstr(Data); %Matlab 2020b
            
            app.hhDropDown.Items = {'00','01','02','03','04','05','06','07','08','09','10','11','12','13','14','15','16','17','18','19','20','21','22','23'};
            app.mmDropDown.Items = {'00','05','10','15','20','25','30','35','40','45','50','55'};
            app.hhDropDown_2.Items = {'00','01','02','03','04','05','06','07','08','09','10','11','12','13','14','15','16','17','18','19','20','21','22','23'};
            app.mmDropDown_2.Items = {'00','05','10','15','20','25','30','35','40','45','50','55'};
            
            hh = hour(datetime('now'));
            hh_str = num2str(hh);
            if (hh<10)
                hh_str = horzcat('0',hh_str);
            end
            
            mm = minute(datetime('now'));
            if (mod(mm,5)~=0)
                mm = round(mm/5)*5;
            end
            mm_str = num2str(mm);
            if (mm<10)
                mm_str = horzcat('0',mm_str);
            end
            
            app.hhDropDown.Value = hh_str;
            app.mmDropDown.Value = mm_str;  
            
            
            %disp('Class Data')
            %disp(class(Data))
            load(char(horzcat('Vehicles\',Data(1,:))))
            app.Image.ImageSource = char(horzcat('img\img_vehicules\','P208','.jpg'));
            
            app.AdressedarriveEditField.Value = 'Expleo, Avenue des Prés, Montigny-le-Bretonneux';
            app.AdressededpartEditField.Value = '1098 Avenue Roger Salengro, Chaville';
        end

        % Drop down opening function: Vhicule1DropDown_2
        function Vhicule1DropDown_2Opening(app, event)
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');

            Length = size(MyFolderInfo,1);
            Data   = []; 
            
            for i=1:Length
                if length(MyFolderInfo(i).name)>=4
                    if (MyFolderInfo(i).name(end-3:end)=='.mat')
                        MyFolderInfo(i).name;
                        Data = [Data;string(MyFolderInfo(i).name)];
                    end
                end
            end
            app.Vhicule1DropDown_2.Items = cellstr(Data);
        end

        % Button pushed function: NouveauVhiculeButton
        function NouveauVhiculeButtonPushed(app, event)
            VehiculeData
            
        end

        % Value changed function: Vhicule1DropDown_2
        function Vhicule1DropDown_2ValueChanged(app, event)
            Vehicule = app.Vhicule1DropDown_2.Value;
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');
            load(char(horzcat('Vehicles\',char(Vehicule))));
            app.Image.ImageSource = char(horzcat('img\img_vehicules\',char(Photo),'.jpg'));
        end

        % Value changed function: PassagersApprox75KgSlider
        function PassagersApprox75KgSliderValueChanged(app, event)
            value = app.PassagersApprox75KgSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParamtresTab;
            
        end

        % Value changed function: ChargeKgSlider
        function ChargeKgSliderValueChanged(app, event)
            value = app.ChargeKgSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MinorTicks(minIdx);
        end

        % Size changed function: ParamtresTab
        function ParamtresTabSizeChanged(app, event)
                       
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
                        
        end

        % Button pushed function: RetournerButton_3
        function RetournerButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.DonnesdentreTab;
            
        end

        % Button pushed function: SuivantButton_3
        function SuivantButton_3Pushed(app, event)
            
            
            % DATOS SEGMENTATION 
            SegmVitesseBool =  app.VitesseMaxCheckBox.Value;
            SegmInterseccBool   = app.IntersectionCheckBox.Value; % NO activado
            SegmRainOptBool   = app.OptionpluieCheckBox.Value;
            SegmPenteBool   = app.PenteCheckBox.Value;
            SegPenteResol = rad2deg(atan(app.EchantillonnagededonnemSpinner.Value/100));
            SegTolerTemps = app.TempsdsirparleconducteursEditField.Value;
            SegInterPente = app.InterpolationdelapenteCheckBox.Value;

            % DATOS OPTIMIZACION
            
            OptimObjetiv1 = '';
            if (app.MinnergieCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'Min energie/');
            end
            if (app.MaxdistanceCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'Max distance/');
            end
            if (app.MaxvitesseCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'Max vitesse/');
            end
            if (app.MintempsCheckBox_3.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'Min temps/');
            end
            if (app.EQdistanceCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'EQ distance/');
            end
            if (app.EQvitesseCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'EQ vitesse/');
            end
            if (app.ConfortCheckBox.Value)
                OptimObjetiv1 = horzcat(OptimObjetiv1,'Confort/');
            end
            
            OptimMethod = '';
            if (app.BoucleOuverteCheckBox.Value)
                OptimMethod = horzcat(OptimObjetiv1,'BO/');
            end
            if (app.BoucleFermeCheckBox.Value)
                OptimMethod = horzcat(OptimObjetiv1,'BF/');
            end
            
            OptimFactDist = app.FacteurdedistanceSlider.Value;
            OptimFactVite = app.FacteurdevitesseSlider.Value;
            OptimFactEner = app.FacteurdnergieSlider.Value;
            OptimFactConf = app.FacteurdeconfortSlider.Value;
            
            disp(OptimObjetiv1)
            
            SampleText = strcat('>>> Data saved:', ...
                '\n    SegmVitesseBool:   ',string(SegmVitesseBool), ...
                '\n    SegmInterseccBool: ',string(SegmInterseccBool), ...
                '\n    SegmRainOptBool:   ',string(SegmRainOptBool), ...
                '\n    SegmPenteBool:     ',string(SegmPenteBool), ...
                '\n    SegPenteResol:     ',string(SegPenteResol), ...
                '\n    SegTolerTemps:     ',string(SegTolerTemps), ...
                '\n    SegInterPente:     ',string(SegInterPente), ...
                '\n         ', ...
                '\n    OptimMethod:       ',string(OptimMethod), ...
                '\n    OptimObjetiv:      ',string(OptimObjetiv1), ...
                '\n    OptimFactDist:     ',string(OptimFactDist), ...
                '\n    OptimFactVite:     ',string(OptimFactVite), ...
                '\n    OptimFactEner:     ',string(OptimFactEner), ...
                '\n    OptimFactConf:     ',string(OptimFactConf), ...
                '\n         ', ...
                '\n>>>> To continue press "GO" for a closed loop contorl', ...
                '\n     or press "Suivant" for a open loop control' ...
                )
            set(app.LoggerTextArea,'Value',compose(SampleText));
            
            set(app.OutToleranceLabel,'Text',string(SegTolerTemps));
            
            app.TabGroup.SelectedTab = app.NaviguerTab;
        end

        % Button pushed function: RetournerButton
        function RetournerButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ParamtresTab;
        end

        % Callback function
        function SuivantButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.AnalyserTab_2
        end

        % Button pushed function: RetournerButton_2
        function RetournerButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.NaviguerTab;
        end

        % Value changed function: AdressededpartEditField
        function AdressededpartEditFieldValueChanged(app, event)
                        
        end

        % Value changed function: AdressedarriveEditField
        function AdressedarriveEditFieldValueChanged(app, event)
            value = app.AdressedarriveEditField.Value;
            
        end

        % Value changed function: VitesseMaxCheckBox
        function VitesseMaxCheckBoxValueChanged(app, event)
                      
        end

        % Value changed function: EchantillonnagededonnemSpinner
        function EchantillonnagededonnemSpinnerValueChanged(app, event)
                        
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            
            app.Label_4.Text = '00%';
            app.Label_5.Text = '00%';
            app.Label_6.Text = '00%';
            app.Label_7.Text = '00%';
            app.Label_8.Text = '00%';
            app.Label_9.Text = '00%';
            app.Label_10.Text = '00%';
            app.Label_11.Text = '00%';
            
            app.Lamp.Color = 'r';
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            app.Lamp_5.Color = 'r';
            app.Lamp_6.Color = 'r';
            app.Lamp_7.Color = 'r';
            app.Lamp_8.Color = 'r';
            pause(0.5);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Data Obtention
            pyversion;

            if count(py.sys.path,'')==0
                insert(py.sys.path,int32(0),'');
            end
            
            %https://www.w3schools.com/colors/colors_hexadecimal.asp#:~:text=A%20hexadecimal%20color%20is%20specified%20with%3A%20%23RRGGBB.%20RR,%28FF%29%20and%20the%20others%20are%20set%20to%2000.
            app.Label_4.Text = 'waiting...';
            app.Lamp.Color = '#fc8f00';
            app.Label_6.Text = 'waiting...';
            app.Lamp_2.Color = '#fc8f00';
            pause(0.5);
            
            Departure = string(app.AdressededpartEditField.Value);
            Arrival   = string(app.AdressedarriveEditField.Value);
            seg_slope_sample = string(rad2deg(atan(app.EchantillonnagededonnemSpinner.Value/100)));
            
            if (app.OptionpluieCheckBox.Value)
                raining_ext = 'True';
            else
                raining_ext = 'False';
            end
            
%             Departure = 'Expleo, Avenue des Prés, Montigny-le-Bretonneux';
%             Arrival   = '1098 Avenue Roger Salengro, Chaville';
            
            Departure = strrep(Departure,' ','_');
            Arrival   = strrep(Arrival,' ','_');

%             command = char(horzcat('python C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\DataGMs.py ',Departure,' ',Arrival,' ',num2str(deg2rad(str2double(seg_slope_sample)))));
%             [status, commandOut] = system(command); 

            NAVecoData = readmatrix('saved_data\DataGMs.csv','OutputType','char');

            Lat  = str2double(NAVecoData(2:end,2));
            Long = str2double(NAVecoData(2:end,3));
            
            fg = figure;
            
            try
                %gx = geoaxes(app.UIAxes4);
                %geoplot(gx,Lat,Long,'g-*')
                geoplot(Lat,Long,'g-*')
                %geolimits([min(lat)-0.2 max(lat)*1.2],[min(lon)-0.2 max(lon)*1.2])
                
                %savefig(fg,"Itinerary/ItineraryIMG.fig")
                exportgraphics(fg, 'Itinerary/ItineraryIMG.png')
                app.Image5.ImageSource = 'Itinerary/ItineraryIMG.png';
                close(fg)
                %app.ItineraryPlot. = openfig('Itinerary/ItineraryIMG.fig');
                
                app.Label_4.Text = '100%';
                app.Lamp.Color = 'g';
                app.Label_6.Text = '100%';
                app.Lamp_2.Color = 'g';
            catch e
                disp(e);
                
                app.Label_4.Text = 'Problem';
                app.Lamp.Color = 'r';
                app.Label_6.Text = 'Problem';
                app.Lamp_2.Color = 'r';
            end
            
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Segmentation 
            app.Label_5.Text = 'waiting...';
            app.Lamp_3.Color = '#fc8f00';
            app.Label_7.Text = 'waiting...';
            app.Lamp_4.Color = '#fc8f00';
            
            pause(0.5);
            
            if (app.VitesseMaxCheckBox.Value)
                seg_speed_ext = 'True';
            else
                seg_speed_ext = 'False';
            end
            if (app.PenteCheckBox.Value)
                seg_slope_ext = 'True';
            else
                seg_slope_ext = 'False';
            end
            seg_slope_sample = string(rad2deg(atan(app.EchantillonnagededonnemSpinner.Value/100)));
            if (app.VitesseMaxCheckBox.Value)
                constant_slope_ext = 'True';
            else
                constant_slope_ext = 'False';
            end
            duration_user = string(app.TempsdsirparleconducteursEditField.Value);


            command = char(horzcat('python C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\segmentation.py ',seg_speed_ext,' ',seg_slope_ext,' ',num2str(deg2rad(str2double(seg_slope_sample))),' ',constant_slope_ext,' ',duration_user));
            [status, commandOut] = system(command); 
            
            try
                %['Num', 'Lat', 'Lng', 'Dist (m)', 'MaxSpeed (m/s)', 'Slope (rad)', 'Altitude (m)', 'Duree (s) T','MIN SPEED C','DistSeg','MIN DUREE C']
                NAVecoSegments = readmatrix('saved_data\Segments.csv','OutputType','char');
                
                save("Segments.mat","NAVecoSegments")
                
                
                DistanceTotal = str2num(string(NAVecoSegments(end,4)));
                DureeTotal = str2num(string(NAVecoSegments(end,8)));
                
                DureeTotalH = floor(DureeTotal/3600);
                DureeTotalM = floor(floor(DureeTotal/60)-floor(DureeTotal/3600)*60);
                DureeTotalS = round(DureeTotal-DureeTotalH*3600-DureeTotalM*60,1);
                
                HourDarrive = datetime('now')+hours(DureeTotalH)+minutes(DureeTotalM)+seconds(DureeTotalS);

                SlopeVect = rad2deg(str2double(NAVecoSegments(:,6)));

                app.Label_18.Text = horzcat(num2str(hour(HourDarrive)),':',num2str(minute(HourDarrive)),':',num2str(round(second(HourDarrive))));
                app.Label_19.Text = horzcat(num2str(DureeTotalH),':',num2str(DureeTotalM),':',num2str(round(DureeTotalS)));
                app.Label_20.Text = num2str(round(tan((max(SlopeVect)-min(SlopeVect)))*100,1));
                app.OutToleranceLabel.Text = num2str(round(DistanceTotal/1000,2));
                
                app.Label_5.Text = '100%';
                app.Lamp_3.Color = 'g';
                app.Label_7.Text = '100%';
                app.Lamp_4.Color = 'g';
                
                
            catch e
                disp(e);
                
                app.Label_5.Text = 'Problem';
                app.Lamp_3.Color = 'r';
                app.Label_7.Text = 'Problem';
                app.Lamp_4.Color = 'r';
                
                % BO no puede con 
                fig = uifigure;
                message = sprintf('Boucle ouvert ne fonction pas avec des segments composés.');
                uialert(fig,message,'Error');                     
            end
            
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OL Optimization Itineraire. 
            
            app.Label_9.Text = 'not available';
            app.Lamp_5.Color = '#fc8f00';
            app.Label_8.Text = 'not available';
            app.Lamp_6.Color = '#fc8f00';
            
            pause(0.5);
%             
%             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Optimization Energie.
            
            if app.BoucleOuverteCheckBox.Value
                app.OptimisationactivednergieBOBFLabel.Text = "Optimizacion active d'ènergie (BO selected)";
            end
            if app.BoucleFermeCheckBox.Value
                app.OptimisationactivednergieBOBFLabel.Text = "Optimizacion active d'ènergie (BF selected)";
            end
            
            %ig = (pi*RPMmax*Rw)/(30*Vmax_EV);      
            Vehicule = app.Vhicule1DropDown_2.Value;
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');
            load(char(horzcat('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles\',char(Vehicule))));
            pause(0.5)
            VehicleData = struct('Nom', 'Sky',...
                                 'M', M,...
                                 'Cr', Cr,...
                                 'Rw', Rw,...
                                 'g', double(9.81),...
                                 'Pair', pair,...
                                 'SCx', SCx,...
                                 'minU', minU,...
                                 'maxU',  maxU,...
                                 'Ig',  Ig,...
                                 'Krpm',  Krpm,...
                                 'Ktorq',  Ktorq,...
                                 'maxRPM',  maxRPM,...
                                 'maxTorq',  maxTorq,...                     
                                 'Tech', Technologie); 
            
            Vehicule1 = app.Vhicule2DropDown.Value;
            disp('Contenido de Vehicule 1')
            if(isempty(Vehicule1))
                disp('Contenido de Vehicule 2 VACIO')
            else
                MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');
                load(char(horzcat('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles\',char(Vehicule1))));
                VehicleData1 = struct('Nom', 'Sky',...
                                     'M', M,...
                                     'Cr', Cr,...
                                     'Rw', Rw,...
                                     'g', double(9.81),...
                                     'Pair', pair,...
                                     'SCx', SCx,...
                                     'minU', minU,...
                                     'maxU',  maxU,...
                                     'Ig',  Ig,...
                                     'Krpm',  Krpm,...
                                     'Ktorq',  Ktorq,...
                                     'maxRPM',  maxRPM,...
                                     'maxTorq',  maxTorq,...                     
                                     'Tech', Technologie); 
            end
            
            addpath('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\OpenLoopFunctions\')  
            
            OptimizationData = struct('MinEneBool', double(app.MinnergieCheckBox.Value),...
                                      'MaxDisBool', double(app.MaxdistanceCheckBox.Value),...
                                      'MaxVitBool', double(app.MaxvitesseCheckBox.Value),...
                                      'MinTemBool', double(app.MintempsCheckBox_3.Value),...
                                      'MinEqDistBool', double(app.EQdistanceCheckBox.Value),...
                                      'MinEqViteBool', double(app.EQvitesseCheckBox.Value),...
                                      'ConfortBool', double(app.ConfortCheckBox.Value),...
                                      'EnergyFactor', double(app.FacteurdnergieSlider.Value)*1e3,...
                                      'DistanceFactor', double(app.FacteurdedistanceSlider.Value)*1,...
                                      'SpeedFactor', double(app.FacteurdevitesseSlider.Value)*1,...
                                      'ConfortFactor', double(app.FacteurdeconfortSlider.Value)*1e-3);
            
            SlopeVFiltre = [str2double(NAVecoSegments(:,4)) str2double(NAVecoSegments(:,6))];

            OrdreFiltre = 200;
            for i=1:length(SlopeVFiltre(:,2))
                if length(SlopeVFiltre(:,2))>1
                    if i<=OrdreFiltre/2
                        SlopeVFiltre(i,2)=mean(SlopeVFiltre(1:(OrdreFiltre/2),2));
                    elseif i>=(length(SlopeVFiltre)-(OrdreFiltre/2))
                        SlopeVFiltre(i,2)=mean(SlopeVFiltre(end-(OrdreFiltre/2):end,2));
                    else
                        SlopeVFiltre(i,2)=mean(SlopeVFiltre(i-(OrdreFiltre/2):i+(OrdreFiltre/2),2));
                    end
                end
            end
          
            app.Label_11.Text = '100%';
            app.Lamp_7.Color = 'g';
            
            disp('Solution Vehicule 1');
            pause(0.5)
            
            V0 = 0;
            Xr0 = 0;
            t0 = 0;
            X0 = 0;
            th = [];
            Xh = [];
            Vh = [];
            Uh = [];
            Xr = [];
            Vlu= [];
            Vld= [];
               
        for j=1:max(str2double(NAVecoSegments(:,1)))         
        %j=4
        Index = find(str2double(NAVecoSegments(:,1))==j);        
        
        MaxSpeed     = [str2double(NAVecoSegments(min(Index):max(Index),4)) str2double(NAVecoSegments(min(Index):max(Index),5))];
                %SlopeV = [str2double(NAVecoSegments(min(Index):max(Index),4)) str2double(NAVecoSegments(min(Index):max(Index),6))];
                SlopeV = SlopeVFiltre(min(Index):max(Index),:);
                SlopeV(:,1)=SlopeV(:,1)-SlopeV(1,1);
                
                if (Index<max(str2double(NAVecoSegments(:,1))))
                    FinalSpeed = str2double(NAVecoSegments(max(Index)+1,5));
                else
                    FinalSpeed = 0;
                end
            
                DurationTemp = str2double(NAVecoSegments(max(Index),11));
            
                if DurationTemp<3
                    DurationTemp = 3;
                end
                
                DestinationTemp = str2double(NAVecoSegments(max(Index),10));
            %     DestinationTemp = 17;
                
                TrajectData = struct('Distance', DestinationTemp,...
                                     'Duration', DurationTemp,...
                                     'OptSpeed', 10,...
                                     'MaxSpeed', MaxSpeed,...
                                     'SlopeVect', SlopeV,...
                                     'MaxSpeedT', str2double(NAVecoSegments(max(Index),5)),...
                                     'MaxSpeedC', str2double(NAVecoSegments(max(Index),5)),...
                                     'MinSpeedC', str2double(NAVecoSegments(max(Index),9)),...
                                     'FinalSpeed', 0,...
                                     'InitSpeed', V0,...
                                     'Slope', mean(SlopeV(:,2)));             
            
                [StatesHist, UnputHist, Temps, solution, problem] = MAIN_OpenLoopOptimizationNAVeco (OptimizationData ,TrajectData, VehicleData);
            %     [ tv, xv, uv ] = simulateSolution( problem, solution, 'ode113', 0.1 );
                
                xx=linspace(solution.T(1,1),solution.tf,1000);
            
                StatesHist = struct('X1', speval(solution,'X',1,xx),'X2', speval(solution,'X',2,xx))
                UnputHist  = struct('U', speval(solution,'U',1,xx))
                Temps         = linspace(solution.T(1,1),solution.tf,1000)';
            
                IndFinal = find(StatesHist.X1>=DestinationTemp,1);
                
                StatesHist.X1 = StatesHist.X1(1:IndFinal);
                StatesHist.X2 = StatesHist.X2(1:IndFinal);
                UnputHist.U   = UnputHist.U(1:IndFinal);
                Temps         = Temps(1:IndFinal);
                    
                th = [th;Temps+t0];
                Xh = [Xh;StatesHist.X1+X0];
                Vh = [Vh;StatesHist.X2];
                Uh = [Uh;UnputHist.U];
            
                Xr = [Xr;ones(size(StatesHist.X1))*round(problem.data.Distance,2)+Xr0];
                Vlu= [Vlu;ones(size(StatesHist.X2))*round(problem.states.xfu(2),2)];    
                Vld= [Vld;ones(size(StatesHist.X2))*round(problem.states.xfl(2),2)];    
            
                t0 = th(end);
                X0 = Xh(end);
                V0 = Vh(end);
                Xr0= Xr(end);
                
                disp(' ')
                disp(' ')
                disp(' Segmento: ')
                disp(j)
                
                %j=1:max(str2double(NAVecoSegments(:,1))) 
                if(isempty(Vehicule1))
                    app.Label_10.Text = horzcat(num2str(round((j/max(str2double(NAVecoSegments(:,1))))*100)),' %')
                else
                    app.Label_10.Text = horzcat(num2str(round((j/max(str2double(NAVecoSegments(:,1))))*50)),' %')
                end
                app.Lamp_8.Color = '#fc8f00'; 
            end
                       
            pause(0.5)
            save('Solution.mat','th','Xh','Vh','Uh','problem','Xr','Vlu','Vld','DureeTotal','DistanceTotal');
            
            if(isempty(Vehicule1))
                disp('Contenido de Vehicule 1 VACIO')
                app.Label_10.Text = '100%';
                app.Lamp_8.Color = 'g';
            else
                app.Label_10.Text = '50%';
                app.Lamp_8.Color = '#fc8f00'; 
                pause(0.5)
                
                disp('Solution Vehicule 2');
                pause(0.5)
                
                V0 = 0;
                Xr0 = 0;
                t0 = 0;
                X0 = 0;
                th = [];
                Xh = [];
                Vh = [];
                Uh = [];
                Xr = [];
                Vlu= [];
                Vld= [];
                                  
                for j=1:max(str2double(NAVecoSegments(:,1)))         
                    %j=4
                    Index = find(str2double(NAVecoSegments(:,1))==j);        
                    
                    MaxSpeed     = [str2double(NAVecoSegments(min(Index):max(Index),4)) str2double(NAVecoSegments(min(Index):max(Index),5))];
                            %SlopeV = [str2double(NAVecoSegments(min(Index):max(Index),4)) str2double(NAVecoSegments(min(Index):max(Index),6))];
                            SlopeV = SlopeVFiltre(min(Index):max(Index),:);
                            SlopeV(:,1)=SlopeV(:,1)-SlopeV(1,1);
                            
                            if (Index<max(str2double(NAVecoSegments(:,1))))
                                FinalSpeed = str2double(NAVecoSegments(max(Index)+1,5));
                            else
                                FinalSpeed = 0;
                            end
                        
                            DurationTemp = str2double(NAVecoSegments(max(Index),11));
                        
                            if DurationTemp<3
                                DurationTemp = 3;
                            end
                            
                            DestinationTemp = str2double(NAVecoSegments(max(Index),10));
                        %     DestinationTemp = 17;
                            
                            TrajectData = struct('Distance', DestinationTemp,...
                                                 'Duration', DurationTemp,...
                                                 'OptSpeed', 10,...
                                                 'MaxSpeed', MaxSpeed,...
                                                 'SlopeVect', SlopeV,...
                                                 'MaxSpeedT', str2double(NAVecoSegments(max(Index),5)),...
                                                 'MaxSpeedC', str2double(NAVecoSegments(max(Index),5)),...
                                                 'MinSpeedC', 0,...
                                                 'FinalSpeed', 0,...
                                                 'InitSpeed', V0,...
                                                 'Slope', mean(SlopeV(:,2)));             
                        
                            [StatesHist, UnputHist, Temps, solution, problem] = MAIN_OpenLoopOptimizationNAVeco (OptimizationData ,TrajectData, VehicleData1);
                        %     [ tv, xv, uv ] = simulateSolution( problem, solution, 'ode113', 0.1 );
                            
                            xx=linspace(solution.T(1,1),solution.tf,1000);
                        
                            StatesHist = struct('X1', speval(solution,'X',1,xx),'X2', speval(solution,'X',2,xx))
                            UnputHist  = struct('U', speval(solution,'U',1,xx))
                            Temps         = linspace(solution.T(1,1),solution.tf,1000)';
                        
                            IndFinal = find(StatesHist.X1>=DestinationTemp,1);
                            
                            StatesHist.X1 = StatesHist.X1(1:IndFinal);
                            StatesHist.X2 = StatesHist.X2(1:IndFinal);
                            UnputHist.U   = UnputHist.U(1:IndFinal);
                            Temps         = Temps(1:IndFinal);
                                
                            th = [th;Temps+t0];
                            Xh = [Xh;StatesHist.X1+X0];
                            Vh = [Vh;StatesHist.X2];
                            Uh = [Uh;UnputHist.U];
                        
                            Xr = [Xr;ones(size(StatesHist.X1))*round(problem.data.Distance,2)+Xr0];
                            Vlu= [Vlu;ones(size(StatesHist.X2))*round(problem.states.xfu(2),2)];    
                            Vld= [Vld;ones(size(StatesHist.X2))*round(problem.states.xfl(2),2)];    
                        
                            t0 = th(end);
                            X0 = Xh(end);
                            V0 = Vh(end);
                            Xr0= Xr(end);
                            
                            disp(' ')
                            disp(' ')
                            disp(' Segmento: ')
                            disp(j)
                            
                            app.Label_10.Text = horzcat(num2str(round((j/max(str2double(NAVecoSegments(:,1))))*50+50)),' %')
                          
                        end
    
                save('Solution1.mat','th','Xh','Vh','Uh','problem','Xr','Vlu','Vld','DureeTotal','DistanceTotal');
            
            
            end
           
%             load('Solution.mat')
            
            pause(0.5)
            
            app.TabGroup.SelectedTab = app.AnalyserTab_2;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Graphs 
            
            app.Image6.ImageSource = 'Itinerary/ItineraryIMG.png';

%             grid(app.UIAxes2_3,"on") ;
%             cla(app.UIAxes2_2) 
                  
            plot(app.UIAxes2_2,str2double(NAVecoSegments(:,4)),tan(deg2rad(str2double(NAVecoSegments(:,6))))*100);
            grid(app.UIAxes2_2,"on");
            xlabel(app.UIAxes2_2,"Distance [m]");
            ylabel(app.UIAxes2_2,"Pourcentage [%]");
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Calcule
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Energie
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Reference
            
            SpeedProfileWLTP;
            
            % Profile Vitesse
            Profile = WLTPClasse1;
            %Profile = WLTPClasse2;
            %Profile = WLTPClasse3b;
            
            [TorqWLTP, VitesseWLTP, OpWLTP, TempsWLTP] = EstimateurCoupleWLTP(NAVecoData,Profile,problem);

            [EnergieWLTP] = EstimateurEnergie(problem,VitesseWLTP,TorqWLTP);
            
            save('SolutionWLTP.mat','TempsWLTP','VitesseWLTP','TorqWLTP','EnergieWLTP','OpWLTP');
            
            OptimizationData = struct('MinEneBool', 0,...
                          'MaxDisBool', 0,...
                          'MaxVitBool', 0,...
                          'MinTemBool', 0,...
                          'MinEqDistBool', 0,...
                          'MinEqViteBool', 1,...
                          'ConfortBool', 1,...
                          'EnergyFactor', 1,...
                          'DistanceFactor', 1,...
                          'SpeedFactor', 1,...
                          'ConfortFactor', 1e-3);

            [TorqMxSpd, VitesseMxSpd, Xh, TempsMxSpd, problem] = EstimateurCoupleMaxSpeed(NAVecoSegments,VehicleData,OptimizationData);
                
            [EnergieMxSpd] = EstimateurEnergie(problem,VitesseMxSpd,TorqMxSpd);
            
            save('SolutionMxSpd.mat','TempsMxSpd','VitesseMxSpd','TorqMxSpd','EnergieMxSpd');
%             load('SolutionWLTP.mat');
%             load('Solution.mat');
            
            [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);
            
            EnergieRef = EnergieMxSpd;
            
            Energie_Sauvegardable = round((EnergieFinal(end)-EnergieRef(end))/1000,1); %KWh
            
            app.Label_13.Text = '100 %';
            app.Label_14.Text = horzcat(num2str(abs(Energie_Sauvegardable)),' KWh');
        end

        % Size changed function: NaviguerTab
        function NaviguerTabSizeChanged(app, event)
            position = app.NaviguerTab.Position;            
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            
        end

        % Callback function: NaviguerTab
        function NaviguerTabButtonDown(app, event)
            
        end

        % Value changed function: MaxdistanceCheckBox
        function MaxdistanceCheckBoxValueChanged(app, event)
            value = app.MaxdistanceCheckBox.Value;
            
            if value
                app.EQdistanceCheckBox.Value = not(value);
            end
            
        end

        % Value changed function: EQdistanceCheckBox
        function EQdistanceCheckBoxValueChanged(app, event)
            value = app.EQdistanceCheckBox.Value;
            
            if value
                app.MaxdistanceCheckBox.Value = not(value);
            end
            
        end

        % Value changed function: MaxvitesseCheckBox
        function MaxvitesseCheckBoxValueChanged(app, event)
            value = app.MaxvitesseCheckBox.Value;
            
            if value
                app.EQvitesseCheckBox.Value = not(value);
            end
            
        end

        % Value changed function: EQvitesseCheckBox
        function EQvitesseCheckBoxValueChanged(app, event)
            value = app.EQvitesseCheckBox.Value;
            
            if value
                app.MaxvitesseCheckBox.Value = not(value);
            end
            
        end

        % Value changed function: BoucleOuverteCheckBox
        function BoucleOuverteCheckBoxValueChanged(app, event)
            value = app.BoucleOuverteCheckBox.Value;
            
            app.BoucleFermeCheckBox.Value = not(value);
            
        end

        % Value changed function: BoucleFermeCheckBox
        function BoucleFermeCheckBoxValueChanged(app, event)
            value = app.BoucleFermeCheckBox.Value;
            
            app.BoucleOuverteCheckBox.Value = not(value);
        end

        % Value changed function: RfrencededistanceCheckBox
        function RfrencededistanceCheckBoxValueChanged(app, event)
            
            
        end

        % Drop down opening function: Vhicule2DropDown
        function Vhicule2DropDownOpening(app, event)
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');

            Length = size(MyFolderInfo,1);
            Data   = []; 
            
            for i=1:Length
                if length(MyFolderInfo(i).name)>=4
                    if (MyFolderInfo(i).name(end-3:end)=='.mat')
                        MyFolderInfo(i).name;
                        Data = [Data;string(MyFolderInfo(i).name)];
                    end
                end
            end
            app.Vhicule2DropDown.Items = cellstr(Data);
        end

        % Value changed function: Vhicule2DropDown
        function Vhicule2DropDownValueChanged(app, event)
            Vehicule1 = app.Vhicule2DropDown.Value;
            MyFolderInfo = dir('C:\Users\crybelloceferin\Documents\MATLAB\GUI\Demostrateur\OpenLoop\V41Segmentation2Vehicules\Vehicles');
            load(char(horzcat('Vehicles\',char(Vehicule1))));
            app.Image.ImageSource = char(horzcat('img\img_vehicules\',char(Photo),'.jpg'));
        end

        % Value changed function: DistanceCheckBox
        function DistanceCheckBoxValueChanged(app, event)
            value = app.DistanceCheckBox.Value;
            
        end

        % Value changed function: LimitationdevitesseCheckBox
        function LimitationdevitesseCheckBoxValueChanged(app, event)
            value = app.LimitationdevitesseCheckBox.Value;
            
        end

        % Value changed function: LimitationdacclrationCheckBox
        function LimitationdacclrationCheckBoxValueChanged(app, event)
            value = app.LimitationdacclrationCheckBox.Value;
            
        end

        % Value changed function: VitesseCheckBox
        function VitesseCheckBoxValueChanged(app, event)
            value = app.VitesseCheckBox.Value;
            
        end

        % Value changed function: AcclrationCheckBox
        function AcclrationCheckBoxValueChanged(app, event)
            value = app.AcclrationCheckBox.Value;
            
        end

        % Value changed function: Energievhicule1CheckBox
        function Energievhicule1CheckBoxValueChanged(app, event)
            value = app.Energievhicule1CheckBox.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            PlotHstEner1 = app.Energievhicule1CheckBox.Value;
            PlotHstEner2 = app.Energievhicule2CheckBox.Value;
            
            if(and(PlotHstEner1,not(PlotHstEner2)))
                load('Solution.mat');
                [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);
            elseif(and(PlotHstEner2,not(PlotHstEner1)))
                load('Solution1.mat');
                [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);
            else
                load('Solution.mat');
                [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);
            end
            
            RefWLTP = app.WLTPCheckBox.Value;
            
            if(RefWLTP)
                load('SolutionWLTP.mat');
                EnergieRef = EnergieWLTP;
                TempsRef   = TempsWLTP;
            else
                load('SolutionMxSpd.mat');
                EnergieRef = EnergieMxSpd;
                TempsRef   = TempsMxSpd;
            end
            
            Energie_Sauvegardable = (EnergieFinal(end)-EnergieRef(end))/1000; %KWh
            
            app.Label_13.Text = '100 %';
            app.Label_14.Text = horzcat(num2str(round(abs(Energie_Sauvegardable))),' KWh');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            load('Segments.mat');
            
            app.Image6.ImageSource = 'Itinerary/ItineraryIMG.png';

%             grid(app.UIAxes2_3,"on") ;
%             cla(app.UIAxes2_2) 
                  
            plot(app.UIAxes2_2,str2double(NAVecoSegments(:,4)),tan(deg2rad(str2double(NAVecoSegments(:,6))))*100);
            grid(app.UIAxes2_2,"on");
            xlabel(app.UIAxes2_2,"Distance [m]");
            ylabel(app.UIAxes2_2,"Pourcentage [%]");
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            
            PlotRefDist = app.RfrencededistanceCheckBox.Value;
            PlotLimVitt = app.LimitationdevitesseCheckBox.Value;
            PlotLimAcce = app.LimitationdacclrationCheckBox.Value;
            PlotHstDist = app.DistanceCheckBox.Value;
            PlotHstVitt = app.VitesseCheckBox.Value;
            PlotHstAcce = app.AcclrationCheckBox.Value;
            
            load('Solution.mat');
            
            grid(app.UIAxes2_3,"on") ;
            cla(app.UIAxes2_3) 
            
            if (PlotHstDist)
                %fg = figure;
                %hold(app.UIAxes);
                plot(app.UIAxes2_3,th,Xh);
                hold(app.UIAxes2_3,"on");
                if (PlotRefDist) 
                    %fg = figure;
                    %hold(app.UIAxes);
                    plot(app.UIAxes2_3,th,Xr,'--r');
                    hold(app.UIAxes2_3,"on");
                    plot(app.UIAxes2_3,th,ones(size(Xh))*DistanceTotal,'--r');
                    hold(app.UIAxes2_3,"on");
                end
                xlabel(app.UIAxes2_3,"Temps [s]");
                ylabel(app.UIAxes2_3,"Distance [m]");
            end
            if (PlotHstVitt)
                %fg = figure;
                %hold(app.UIAxes);
                plot(app.UIAxes2_3,th,Vh);
                hold(app.UIAxes2_3,"on");
                if (PlotLimVitt)
                    %fg = figure;
                    plot(app.UIAxes2_3,th,Vlu,'--r');
                    hold(app.UIAxes2_3,"on");
                    plot(app.UIAxes2_3,th,Vld,'--r');
                    hold(app.UIAxes2_3,"on");
                end
                xlabel(app.UIAxes2_3,"Temps [s]");
                ylabel(app.UIAxes2_3,"Vitesse [m/s]");
            end
            if (PlotHstEner1)
                
                load('Solution.mat');
                
                [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);          

                plot(app.UIAxes2_3,th,EnergieFinal/1000,'r');
                hold(app.UIAxes2_3,"on");
                plot(app.UIAxes2_3,TempsRef,EnergieRef/1000,'b--');
                hold(app.UIAxes2_3,"on");
                
                xlabel(app.UIAxes2_3,"Temps [s]");
                ylabel(app.UIAxes2_3,"Energie [KWh]");
                
                legend(app.UIAxes2_3,'Véhicule 1','Référence');
            end
            if (PlotHstEner2)
                
                load('Solution1.mat');
                
                [EnergieFinal] = EstimateurEnergie(problem,Vh,Uh);          

                plot(app.UIAxes2_3,th,EnergieFinal/1000,'k');
                hold(app.UIAxes2_3,"on");
                if (PlotHstEner1)
                    legend(app.UIAxes2_3,'Véhicule 1','V1 WLTP','Véhicule 2');
                else
                    plot(app.UIAxes2_3,TempsRef,EnergieRef/1000,'b--');
                    hold(app.UIAxes2_3,"on");
                    legend(app.UIAxes2_3,'Véhicule 2','V1WLTP');
                end
                xlabel(app.UIAxes2_3,"Temps [s]");
                ylabel(app.UIAxes2_3,"Energie [KWh]");
                
            end
            if (PlotHstAcce)
                
                plot(app.UIAxes2_3,th,[0;diff(Vh)./diff(th)])
                hold(app.UIAxes2_3,"on");
                if (PlotLimAcce)
                    %fg = figure;
                    plot(app.UIAxes2_3,th,ones(size(Xh))*problem.data.grav*0.2,'--r');
                    hold(app.UIAxes2_3,"on");
                    plot(app.UIAxes2_3,th,ones(size(Xh))*problem.data.grav*-0.2,'--r');
                    hold(app.UIAxes2_3,"on");
                end
                
                xlabel(app.UIAxes2_3,"Temps [s]");
                ylabel(app.UIAxes2_3,"Acceleration [m/s2]");
            end
            
            
        end

        % Callback function: AnalyserTab_2
        function AnalyserTab_2ButtonDown(app, event)
            
        end

        % Value changed function: WLTPCheckBox
        function WLTPCheckBoxValueChanged(app, event)
            value = app.WLTPCheckBox.Value;
            
            if (value)
                app.VitesseMaximaleCheckBox.Value = false;
            end
        end

        % Value changed function: VitesseMaximaleCheckBox
        function VitesseMaximaleCheckBoxValueChanged(app, event)
            value = app.VitesseMaximaleCheckBox.Value;
            
            if (value)
                app.WLTPCheckBox.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DonnesdentreTab = uitab(app.TabGroup);
            app.DonnesdentreTab.Title = 'Données d''entrée';
            app.SuivantButton = uibutton(app.DonnesdentreTab, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant';
            app.Image2 = uiimage(app.DonnesdentreTab);
            app.Image2.ImageSource = 'Logo.png';
            app.InformationsduvhiculePanel = uipanel(app.DonnesdentreTab);
            app.InformationsduvhiculePanel.Title = 'Informations du véhicule';
            app.NouveauVhiculeButton = uibutton(app.InformationsduvhiculePanel, 'push');
            app.NouveauVhiculeButton.ButtonPushedFcn = createCallbackFcn(app, @NouveauVhiculeButtonPushed, true);
            app.NouveauVhiculeButton.Text = 'Nouveau Véhicule';
            app.Image = uiimage(app.InformationsduvhiculePanel);
            app.Vhicule1DropDown_2Label = uilabel(app.InformationsduvhiculePanel);
            app.Vhicule1DropDown_2Label.Text = 'Véhicule  1';
            app.Vhicule1DropDown_2 = uidropdown(app.InformationsduvhiculePanel);
            app.Vhicule1DropDown_2.Items = {};
            app.Vhicule1DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @Vhicule1DropDown_2Opening, true);
            app.Vhicule1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Vhicule1DropDown_2ValueChanged, true);
            app.Vhicule1DropDown_2.Value = {};
            app.Vhicule2Label = uilabel(app.InformationsduvhiculePanel);
            app.Vhicule2Label.Text = 'Véhicule 2 ';
            app.Vhicule2DropDown = uidropdown(app.InformationsduvhiculePanel);
            app.Vhicule2DropDown.Items = {};
            app.Vhicule2DropDown.DropDownOpeningFcn = createCallbackFcn(app, @Vhicule2DropDownOpening, true);
            app.Vhicule2DropDown.ValueChangedFcn = createCallbackFcn(app, @Vhicule2DropDownValueChanged, true);
            app.Vhicule2DropDown.Value = {};
            app.InformationdupoidsPanel = uipanel(app.DonnesdentreTab);
            app.InformationdupoidsPanel.Title = 'Information du poids';
            app.PassagersApprox75KgSliderLabel = uilabel(app.InformationdupoidsPanel);
            app.PassagersApprox75KgSliderLabel.Text = {'Passagers'; '(Approx 75 [Kg])'};
            app.PassagersApprox75KgSlider = uislider(app.InformationdupoidsPanel);
            app.PassagersApprox75KgSlider.Limits = [0 4];
            app.PassagersApprox75KgSlider.MajorTicks = [0 1 2 3 4];
            app.PassagersApprox75KgSlider.ValueChangedFcn = createCallbackFcn(app, @PassagersApprox75KgSliderValueChanged, true);
            app.PassagersApprox75KgSlider.MinorTicks = [0 1 2 3 4];
            app.ChargeKgSliderLabel = uilabel(app.InformationdupoidsPanel);
            app.ChargeKgSliderLabel.Text = 'Charge [Kg]';
            app.ChargeKgSlider = uislider(app.InformationdupoidsPanel);
            app.ChargeKgSlider.ValueChangedFcn = createCallbackFcn(app, @ChargeKgSliderValueChanged, true);
            app.InformationsdutrajetPanel = uipanel(app.DonnesdentreTab);
            app.InformationsdutrajetPanel.Title = 'Informations du trajet';
            app.AdressededpartEditFieldLabel = uilabel(app.InformationsdutrajetPanel);
            app.AdressededpartEditFieldLabel.Text = 'Adresse de départ ';
            app.AdressededpartEditField = uieditfield(app.InformationsdutrajetPanel, 'text');
            app.AdressededpartEditField.ValueChangedFcn = createCallbackFcn(app, @AdressededpartEditFieldValueChanged, true);
            app.AdressedarriveEditFieldLabel = uilabel(app.InformationsdutrajetPanel);
            app.AdressedarriveEditFieldLabel.Text = 'Adresse d''arrivée  ';
            app.AdressedarriveEditField = uieditfield(app.InformationsdutrajetPanel, 'text');
            app.AdressedarriveEditField.ValueChangedFcn = createCallbackFcn(app, @AdressedarriveEditFieldValueChanged, true);
            app.hhDropDownLabel = uilabel(app.InformationsdutrajetPanel);
            app.hhDropDownLabel.Text = 'hh:';
            app.hhDropDown = uidropdown(app.InformationsdutrajetPanel);
            app.hhDropDown.Items = {};
            app.hhDropDown.Value = {};
            app.HeuredarriveLabel = uilabel(app.InformationsdutrajetPanel);
            app.HeuredarriveLabel.Text = 'Heure d''arrivée';
            app.mmDropDownLabel = uilabel(app.InformationsdutrajetPanel);
            app.mmDropDownLabel.Text = 'mm:';
            app.mmDropDown = uidropdown(app.InformationsdutrajetPanel);
            app.mmDropDown.Items = {};
            app.mmDropDown.Value = {};
            app.TolrancedutempsLabel = uilabel(app.InformationsdutrajetPanel);
            app.TolrancedutempsLabel.Text = {'Tolérance du'; 'temps [+-]'};
            app.hhDropDown_2Label = uilabel(app.InformationsdutrajetPanel);
            app.hhDropDown_2Label.Text = 'hh:';
            app.hhDropDown_2 = uidropdown(app.InformationsdutrajetPanel);
            app.hhDropDown_2.Items = {};
            app.hhDropDown_2.Value = {};
            app.mmDropDown_2Label = uilabel(app.InformationsdutrajetPanel);
            app.mmDropDown_2Label.Text = 'mm:';
            app.mmDropDown_2 = uidropdown(app.InformationsdutrajetPanel);
            app.mmDropDown_2.Items = {};
            app.mmDropDown_2.Value = {};
            app.ParamtresTab = uitab(app.TabGroup);
            app.ParamtresTab.SizeChangedFcn = createCallbackFcn(app, @ParamtresTabSizeChanged, true);
            app.ParamtresTab.Title = 'Paramètres';
            app.SegmentationPanel = uipanel(app.ParamtresTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.InterpolationdelapenteCheckBox = uicheckbox(app.SegmentationPanel);
            app.InterpolationdelapenteCheckBox.Text = 'Interpolation de la pente';
            app.EchantillonnagededonnemSpinnerLabel = uilabel(app.SegmentationPanel);
            app.EchantillonnagededonnemSpinnerLabel.Text = 'Echantillonnage de donnée [m]';
            app.EchantillonnagededonnemSpinner = uispinner(app.SegmentationPanel);
            app.EchantillonnagededonnemSpinner.Step = 0.5;
            app.EchantillonnagededonnemSpinner.Limits = [1 10];
            app.EchantillonnagededonnemSpinner.ValueChangedFcn = createCallbackFcn(app, @EchantillonnagededonnemSpinnerValueChanged, true);
            app.EchantillonnagededonnemSpinner.Value = 10;
            app.ParamtresdelasegmentationPanel = uipanel(app.SegmentationPanel);
            app.ParamtresdelasegmentationPanel.Title = 'Paramètres de la segmentation';
            app.VitesseMaxCheckBox = uicheckbox(app.ParamtresdelasegmentationPanel);
            app.VitesseMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @VitesseMaxCheckBoxValueChanged, true);
            app.VitesseMaxCheckBox.Text = 'Vitesse Max';
            app.VitesseMaxCheckBox.Value = true;
            app.PenteCheckBox = uicheckbox(app.ParamtresdelasegmentationPanel);
            app.PenteCheckBox.Text = 'Pente';
            app.PenteCheckBox.Value = true;
            app.IntersectionCheckBox = uicheckbox(app.ParamtresdelasegmentationPanel);
            app.IntersectionCheckBox.Enable = 'off';
            app.IntersectionCheckBox.Text = 'Intersection';
            app.OptionpluieCheckBox = uicheckbox(app.ParamtresdelasegmentationPanel);
            app.OptionpluieCheckBox.Text = 'Option pluie ';
            app.TempsdsirparleconducteursEditFieldLabel = uilabel(app.SegmentationPanel);
            app.TempsdsirparleconducteursEditFieldLabel.Text = 'Temps désiré par le conducteur [s]';
            app.TempsdsirparleconducteursEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.TempsdsirparleconducteursEditField.Value = 1000;
            app.RetournerButton_3 = uibutton(app.ParamtresTab, 'push');
            app.RetournerButton_3.ButtonPushedFcn = createCallbackFcn(app, @RetournerButton_3Pushed, true);
            app.RetournerButton_3.Text = 'Retourner';
            app.SuivantButton_3 = uibutton(app.ParamtresTab, 'push');
            app.SuivantButton_3.ButtonPushedFcn = createCallbackFcn(app, @SuivantButton_3Pushed, true);
            app.SuivantButton_3.Text = 'Suivant';
            app.Image2_4 = uiimage(app.ParamtresTab);
            app.Image2_4.ImageSource = 'Logo.png';
            app.ParamtredecontroleoptimalButtonGroup = uibuttongroup(app.ParamtresTab);
            app.ParamtredecontroleoptimalButtonGroup.Title = 'Paramètre de controle optimal';
            app.FacteurdevitesseSliderLabel = uilabel(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdevitesseSliderLabel.Text = 'Facteur de vitesse  ';
            app.FacteurdevitesseSlider = uislider(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdevitesseSlider.Limits = [0 1];
            app.FacteurdedistanceSliderLabel = uilabel(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdedistanceSliderLabel.Text = 'Facteur de distance';
            app.FacteurdedistanceSlider = uislider(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdedistanceSlider.Limits = [0 1];
            app.FacteurdedistanceSlider.Value = 1;
            app.FacteurdnergieSliderLabel = uilabel(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdnergieSliderLabel.Text = 'Facteur d''énergie ';
            app.FacteurdnergieSlider = uislider(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdnergieSlider.Limits = [0 1];
            app.FacteurdnergieSlider.Value = 1;
            app.FacteurdeconfortSliderLabel = uilabel(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdeconfortSliderLabel.Text = 'Facteur de confort  ';
            app.FacteurdeconfortSlider = uislider(app.ParamtredecontroleoptimalButtonGroup);
            app.FacteurdeconfortSlider.Limits = [0 1];
            app.FacteurdeconfortSlider.Value = 1;
            app.ParamtredoptimisationPanel = uipanel(app.ParamtresTab);
            app.ParamtredoptimisationPanel.Title = 'Paramètre d''optimisation';
            app.MinnergieCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.MinnergieCheckBox.Text = 'Min ènergie';
            app.MinnergieCheckBox.Value = true;
            app.MaxdistanceCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.MaxdistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxdistanceCheckBoxValueChanged, true);
            app.MaxdistanceCheckBox.Text = 'Max distance';
            app.MaxvitesseCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.MaxvitesseCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaxvitesseCheckBoxValueChanged, true);
            app.MaxvitesseCheckBox.Text = 'Max vitesse';
            app.MintempsCheckBox_3 = uicheckbox(app.ParamtredoptimisationPanel);
            app.MintempsCheckBox_3.Enable = 'off';
            app.MintempsCheckBox_3.Text = 'Min temps';
            app.EQdistanceCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.EQdistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @EQdistanceCheckBoxValueChanged, true);
            app.EQdistanceCheckBox.Text = 'EQ distance';
            app.EQdistanceCheckBox.Value = true;
            app.EQvitesseCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.EQvitesseCheckBox.ValueChangedFcn = createCallbackFcn(app, @EQvitesseCheckBoxValueChanged, true);
            app.EQvitesseCheckBox.Text = 'EQ vitesse';
            app.BoucleOuverteCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.BoucleOuverteCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoucleOuverteCheckBoxValueChanged, true);
            app.BoucleOuverteCheckBox.Text = 'Boucle Ouverte';
            app.BoucleOuverteCheckBox.Value = true;
            app.BoucleFermeCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.BoucleFermeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoucleFermeCheckBoxValueChanged, true);
            app.BoucleFermeCheckBox.Text = 'Boucle Fermée';
            app.ConfortCheckBox = uicheckbox(app.ParamtredoptimisationPanel);
            app.ConfortCheckBox.Text = 'Confort';
            app.ConfortCheckBox.Value = true;
            app.NaviguerTab = uitab(app.TabGroup);
            app.NaviguerTab.SizeChangedFcn = createCallbackFcn(app, @NaviguerTabSizeChanged, true);
            app.NaviguerTab.Title = 'Naviguer';
            app.RetournerButton = uibutton(app.NaviguerTab, 'push');
            app.RetournerButton.ButtonPushedFcn = createCallbackFcn(app, @RetournerButtonPushed, true);
            app.RetournerButton.Text = 'Retourner';
            app.GOButton = uibutton(app.NaviguerTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.ProgressionPanel = uipanel(app.NaviguerTab);
            app.ProgressionPanel.Title = 'Progression ';
            app.SegmentationLabel = uilabel(app.ProgressionPanel);
            app.SegmentationLabel.Text = 'Segmentation:';
            app.CalculditinraireLabel = uilabel(app.ProgressionPanel);
            app.CalculditinraireLabel.Text = 'Calcul d''itinéraire';
            app.DtectiondescontraintesLabel = uilabel(app.ProgressionPanel);
            app.DtectiondescontraintesLabel.Text = 'Détection des contraintes';
            app.DtectiondetraficLabel_2 = uilabel(app.ProgressionPanel);
            app.DtectiondetraficLabel_2.Text = 'Détection de trafic';
            app.SegmentationdetrajetLabel = uilabel(app.ProgressionPanel);
            app.SegmentationdetrajetLabel.Text = 'Segmentation de trajet';
            app.OptimisationditinraireBOLabel = uilabel(app.ProgressionPanel);
            app.OptimisationditinraireBOLabel.Text = 'Optimisation d''itinéraire (BO)';
            app.CalculditinraireoptimalLabel = uilabel(app.ProgressionPanel);
            app.CalculditinraireoptimalLabel.Text = 'Calcul d''itinéraire optimal';
            app.CalculdnergieminimaleLabel = uilabel(app.ProgressionPanel);
            app.CalculdnergieminimaleLabel.Text = 'Calcul d''énergie minimale';
            app.OptimisationactivednergieBOBFLabel = uilabel(app.ProgressionPanel);
            app.OptimisationactivednergieBOBFLabel.Text = 'Optimisation active d''énergie (BO - BF)';
            app.ConfigurationdeloptimisationLabel = uilabel(app.ProgressionPanel);
            app.ConfigurationdeloptimisationLabel.Text = 'Configuration de l''optimisation';
            app.StatutdetrajetLabel = uilabel(app.ProgressionPanel);
            app.StatutdetrajetLabel.Text = 'Statut de trajet';
            app.Lamp = uilamp(app.ProgressionPanel);
            app.Lamp_2 = uilamp(app.ProgressionPanel);
            app.Lamp_3 = uilamp(app.ProgressionPanel);
            app.Lamp_4 = uilamp(app.ProgressionPanel);
            app.Lamp_5 = uilamp(app.ProgressionPanel);
            app.Lamp_6 = uilamp(app.ProgressionPanel);
            app.Lamp_7 = uilamp(app.ProgressionPanel);
            app.Lamp_8 = uilamp(app.ProgressionPanel);
            app.Label_4 = uilabel(app.ProgressionPanel);
            app.Label_4.Text = '00%';
            app.Label_5 = uilabel(app.ProgressionPanel);
            app.Label_5.Text = '00%';
            app.Label_6 = uilabel(app.ProgressionPanel);
            app.Label_6.Text = '00%';
            app.Label_7 = uilabel(app.ProgressionPanel);
            app.Label_7.Text = '00%';
            app.Label_8 = uilabel(app.ProgressionPanel);
            app.Label_8.Text = '00%';
            app.Label_9 = uilabel(app.ProgressionPanel);
            app.Label_9.Text = '00%';
            app.Label_11 = uilabel(app.ProgressionPanel);
            app.Label_11.Text = '00%';
            app.Label_10 = uilabel(app.ProgressionPanel);
            app.Label_10.Text = '00%';
            app.Image2_2 = uiimage(app.NaviguerTab);
            app.Image2_2.ImageSource = 'Logo.png';
            app.CaractristiquesdelitinraireEstimparGooglePanel = uipanel(app.NaviguerTab);
            app.CaractristiquesdelitinraireEstimparGooglePanel.Title = 'Caractéristiques de l''itinéraire (Estimé par Google)';
            app.HeuredarriveestimehhmmssLabel = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.HeuredarriveestimehhmmssLabel.Text = 'Heure d''arrivée estimée  [hh:mm:ss]';
            app.DurehhmmssLabel = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.DurehhmmssLabel.Text = 'Durée                               [hh:mm:ss]';
            app.VariationdepenteestimeLabel = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.VariationdepenteestimeLabel.Text = 'Variation de pente estimée          [%]';
            app.Label_18 = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.Label_18.Text = '00:00:00';
            app.Label_19 = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.Label_19.Text = '0000000';
            app.Label_20 = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.Label_20.Text = '00';
            app.DistanceestimeKmLabel = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.DistanceestimeKmLabel.Text = 'Distance estimée                       [Km]';
            app.OutToleranceLabel = uilabel(app.CaractristiquesdelitinraireEstimparGooglePanel);
            app.OutToleranceLabel.Text = '0000000';
            app.EnregistreurPanel = uipanel(app.NaviguerTab);
            app.EnregistreurPanel.Title = 'Enregistreur';
            app.LoggerTextArea = uitextarea(app.EnregistreurPanel);
            app.Image5 = uiimage(app.NaviguerTab);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.AnalyserTab_2 = uitab(app.TabGroup);
            app.AnalyserTab_2.Title = 'Analyser';
            app.RetournerButton_2 = uibutton(app.AnalyserTab_2, 'push');
            app.RetournerButton_2.ButtonPushedFcn = createCallbackFcn(app, @RetournerButton_2Pushed, true);
            app.RetournerButton_2.Text = 'Retourner';
            app.ExporterButton = uibutton(app.AnalyserTab_2, 'push');
            app.ExporterButton.Text = 'Exporter';
            app.Panel = uipanel(app.AnalyserTab_2);
            app.RfrencededistanceCheckBox = uicheckbox(app.Panel);
            app.RfrencededistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @RfrencededistanceCheckBoxValueChanged, true);
            app.RfrencededistanceCheckBox.Text = 'Référence de distance';
            app.LimitationdevitesseCheckBox = uicheckbox(app.Panel);
            app.LimitationdevitesseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitationdevitesseCheckBoxValueChanged, true);
            app.LimitationdevitesseCheckBox.Text = 'Limitation de vitesse';
            app.LimitationdacclrationCheckBox = uicheckbox(app.Panel);
            app.LimitationdacclrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitationdacclrationCheckBoxValueChanged, true);
            app.LimitationdacclrationCheckBox.Text = 'Limitation d''accélération';
            app.DistanceCheckBox = uicheckbox(app.Panel);
            app.DistanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistanceCheckBoxValueChanged, true);
            app.DistanceCheckBox.Text = 'Distance';
            app.VitesseCheckBox = uicheckbox(app.Panel);
            app.VitesseCheckBox.ValueChangedFcn = createCallbackFcn(app, @VitesseCheckBoxValueChanged, true);
            app.VitesseCheckBox.Text = 'Vitesse';
            app.AcclrationCheckBox = uicheckbox(app.Panel);
            app.AcclrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AcclrationCheckBoxValueChanged, true);
            app.AcclrationCheckBox.Text = 'Accélération';
            app.Energievhicule1CheckBox = uicheckbox(app.Panel);
            app.Energievhicule1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Energievhicule1CheckBoxValueChanged, true);
            app.Energievhicule1CheckBox.Text = 'Energie véhicule 1';
            app.Energievhicule2CheckBox = uicheckbox(app.Panel);
            app.Energievhicule2CheckBox.Text = 'Energie véhicule 2';
            app.WLTPCheckBox = uicheckbox(app.Panel);
            app.WLTPCheckBox.ValueChangedFcn = createCallbackFcn(app, @WLTPCheckBoxValueChanged, true);
            app.WLTPCheckBox.Text = 'WLTP';
            app.WLTPCheckBox.Value = true;
            app.VitesseMaximaleCheckBox = uicheckbox(app.Panel);
            app.VitesseMaximaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @VitesseMaximaleCheckBoxValueChanged, true);
            app.VitesseMaximaleCheckBox.Text = 'Vitesse Maximale';
            app.NiveaudoptimisationLabel = uilabel(app.AnalyserTab_2);
            app.NiveaudoptimisationLabel.Text = 'Niveau d''optimisation';
            app.Label_13 = uilabel(app.AnalyserTab_2);
            app.Label_13.Text = '00';
            app.Label_14 = uilabel(app.AnalyserTab_2);
            app.Label_14.Text = '00';
            app.DiffrencednergieavecrfrenceLabel = uilabel(app.AnalyserTab_2);
            app.DiffrencednergieavecrfrenceLabel.Text = {'Différence d''énergie'; 'avec référence'};
            app.Image2_3 = uiimage(app.AnalyserTab_2);
            app.Image2_3.ImageSource = 'Logo.png';
            app.Image6 = uiimage(app.AnalyserTab_2);
            app.PlotButton = uibutton(app.AnalyserTab_2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes2_2 = uiaxes(app.AnalyserTab_2);
            title(app.UIAxes2_2, 'Pente')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.AnalyserTab_2);
            title(app.UIAxes2_3, 'Data')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NAVecoV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end